classdef visible < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        LeftPanel         matlab.ui.container.Panel
        TextArea          matlab.ui.control.TextArea
        Label_7           matlab.ui.control.Label
        F_max             matlab.ui.control.NumericEditField
        EditField_5Label  matlab.ui.control.Label
        fz                matlab.ui.control.NumericEditField
        Label_4           matlab.ui.control.Label
        xe                matlab.ui.control.NumericEditField
        Label_5           matlab.ui.control.Label
        m                 matlab.ui.control.NumericEditField
        Label_6           matlab.ui.control.Label
        g                 matlab.ui.control.NumericEditField
        Label_3           matlab.ui.control.Label
        Kv                matlab.ui.control.Slider
        Label_2           matlab.ui.control.Label
        Kp                matlab.ui.control.Slider
        Label             matlab.ui.control.Label
        RightPanel        matlab.ui.container.Panel
        UIAxes            matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        x0                double  % 初始位置
        v0                double  % 初始速度
        dx_t              double  % 位置差值阈值
        dv_t              double  % 速度差值阈值
    end
    
    methods (Access = private)
        
        function results = PIDs(app)
            controller=@(xn,vn)PID_p(app.xe.Value,xn,vn,app.Kp.Value,app.Kv.Value,app.F_max.Value);
            event_func=@(t,x) e(t,x,app.xe.Value,app.dx_t,app.dv_t);
            opts=odeset('Events',event_func);

            [t,x,te]=ode45(@(t,x)dynamics(t,x,controller,app.fz.Value,app.m.Value,app.g.Value),[0,20],[app.x0;app.v0],opts);
            plot(app.UIAxes,t,x(:,1),'r-',t,x(:,2),'b-');
            if ~isempty(te)
                results=te(1);
            else
                results=inf;
            end
            app.TextArea.Value=num2str(results);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.dx_t=0.1;%位置差值检测阈值
            app.dv_t=0.1;%速度差值检测阈值
            app.x0=0;
            app.v0=0;            
            % 设置UI组件默认值
            app.Kp.Value = 1;
            app.Kv.Value = 10;
            app.g.Value = 9.81;
            app.m.Value = 1.0;
            app.xe.Value = 5.0;
            app.fz.Value = 0.1;
            app.F_max.Value = 20;
            % 初始化绘图
            app.PIDs();
            xlim(app.UIAxes, [0, 20]);       % X轴范围：0到20秒
            ylim(app.UIAxes, [-1, 10]);      % Y轴范围：-1到10
        end

        % Value changing function: Kp
        function KpValueChanging(app, event)
            app.Kp.Value = event.Value;
            app.PIDs();
        end

        % Value changing function: Kv
        function KvValueChanging(app, event)
            app.Kv.Value = event.Value;
            app.PIDs();
        end

        % Value changed function: xe
        function xeValueChanged(app, event)
            app.xe.Value = event.Value;
            app.PIDs();
        end

        % Value changed function: g
        function gValueChanged(app, event)
            app.g.Value = event.Value;
            app.PIDs();
        end

        % Value changed function: m
        function mValueChanged(app, event)
            app.m.Value = event.Value;
            app.PIDs();
        end

        % Value changed function: fz
        function fzValueChanged(app, event)
            app.fz.Value = event.Value;
            app.PIDs();
        end

        % Value changed function: F_max
        function F_maxValueChanged(app, event)
            app.F_max.Value = event.Value;
            app.PIDs();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {476, 476};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {342, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = {'位置差值'; '比例系数'};
            app.Kp = uislider(app.LeftPanel);
            app.Kp.Limits = [0 10];
            app.Kp.ValueChangingFcn = createCallbackFcn(app, @KpValueChanging, true);
            app.Kp.Value = 1;
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = {'速度差值'; '比例系数'};
            app.Kv = uislider(app.LeftPanel);
            app.Kv.ValueChangingFcn = createCallbackFcn(app, @KvValueChanging, true);
            app.Kv.Value = 10;
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = '重力加速度';
            app.g = uieditfield(app.LeftPanel, 'numeric');
            app.g.ValueChangedFcn = createCallbackFcn(app, @gValueChanged, true);
            app.g.Value = 9.81;
            app.Label_6 = uilabel(app.LeftPanel);
            app.Label_6.Text = '无人机质量';
            app.m = uieditfield(app.LeftPanel, 'numeric');
            app.m.ValueChangedFcn = createCallbackFcn(app, @mValueChanged, true);
            app.m.Value = 1;
            app.Label_5 = uilabel(app.LeftPanel);
            app.Label_5.Text = '期望位置';
            app.xe = uieditfield(app.LeftPanel, 'numeric');
            app.xe.ValueChangedFcn = createCallbackFcn(app, @xeValueChanged, true);
            app.xe.Value = 5;
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = '阻尼系数';
            app.fz = uieditfield(app.LeftPanel, 'numeric');
            app.fz.ValueChangedFcn = createCallbackFcn(app, @fzValueChanged, true);
            app.fz.Value = 0.1;
            app.EditField_5Label = uilabel(app.LeftPanel);
            app.EditField_5Label.Text = '最大出力';
            app.F_max = uieditfield(app.LeftPanel, 'numeric');
            app.F_max.ValueChangedFcn = createCallbackFcn(app, @F_maxValueChanged, true);
            app.F_max.Value = 20;
            app.Label_7 = uilabel(app.LeftPanel);
            app.Label_7.Text = '调节时间';
            app.TextArea = uitextarea(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '实时位置与速度')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'x')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = visible

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end