classdef visible_pi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        LeftPanel         matlab.ui.container.Panel
        disturbance_1     matlab.ui.control.Switch
        Label_12          matlab.ui.control.Label
        disturbance_3     matlab.ui.control.NumericEditField
        Label_11          matlab.ui.control.Label
        disturbance_2     matlab.ui.control.NumericEditField
        Label_10          matlab.ui.control.Label
        Ki_v              matlab.ui.control.Slider
        Label_9           matlab.ui.control.Label
        Kp_v              matlab.ui.control.Slider
        Label_8           matlab.ui.control.Label
        TextArea          matlab.ui.control.TextArea
        Label_7           matlab.ui.control.Label
        F_max             matlab.ui.control.NumericEditField
        EditField_5Label  matlab.ui.control.Label
        fz                matlab.ui.control.NumericEditField
        Label_4           matlab.ui.control.Label
        xe                matlab.ui.control.NumericEditField
        Label_5           matlab.ui.control.Label
        m                 matlab.ui.control.NumericEditField
        Label_6           matlab.ui.control.Label
        g                 matlab.ui.control.NumericEditField
        Label_3           matlab.ui.control.Label
        Ki_x              matlab.ui.control.Slider
        Label_2           matlab.ui.control.Label
        Kp_x              matlab.ui.control.Slider
        Label             matlab.ui.control.Label
        RightPanel        matlab.ui.container.Panel
        UIAxes            matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        x0                double  % 初始位置
        v0                double  % 初始速度
        dx_t              double  % 位置差值阈值
        dv_t              double  % 速度差值阈值
        disturbance       double 
    end
    
    methods (Access = private)
        
        function results = PIDs(app)
            controller=@(t,x)PID_pi(app.xe.Value,x,app.Kp_x.Value,app.Ki_x.Value,app.Kp_v.Value,app.Ki_v.Value,app.F_max.Value,t);
            event_func=@(t,x)e(t,x,app.xe.Value,app.dx_t,app.dv_t);
            opts=odeset('Events',event_func);

            [t,x,te]=ode45(@(t,x)dynamics_pi(t,x,controller,app.fz.Value,app.m.Value,app.g.Value,app.disturbance),[0,20],[app.x0;app.v0],opts);
            acceleration = zeros(size(t));
            for i = 1:length(t)
                dydt = dynamics_pi(t(i), x(i,:)',controller,app.fz.Value,app.m.Value,app.g.Value,app.disturbance);
                acceleration(i) = dydt(2);
            end
            plot(app.UIAxes,t,x(:,1),'r-',t,x(:,2),'b-',t,acceleration,'g-');
            legend(app.UIAxes,'位置','速度','加速度','northeast');
            if ~isempty(te)
                results=te(1);
            else
                results=inf;
            end
            app.TextArea.Value=num2str(results);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.dx_t=0.1;%位置差值检测阈值
            app.dv_t=0.1;%速度差值检测阈值
            app.x0=0;
            app.v0=0;
            app.disturbance = zeros(1,3);
            % 设置UI组件默认值
            app.Kp_x.Value = 1;
            app.Ki_x.Value = 1;
            app.Kp_v.Value = 10;
            app.Ki_v.Value = 10;
            app.g.Value = 9.81;
            app.m.Value = 1.0;
            app.xe.Value = 5.0;
            app.fz.Value = 0.1;
            app.F_max.Value = 20;
            app.disturbance(1) = 0;
            app.disturbance(2) = 0;
            app.disturbance(3) = 0;
            % 初始化绘图
            app.PIDs();
            xlim(app.UIAxes, [0, 20]);       % X轴范围：0到20秒
            ylim(app.UIAxes, [-1, 10]);      % Y轴范围：-1到10
        end

        % Value changing function: Kp_x
        function Kp_xValueChanging(app, event)
            app.Kp_x.Value = event.Value;
            app.PIDs();
        end

        % Value changing function: Ki_x
        function Ki_xValueChanging(app, event)
            app.Ki_x.Value = event.Value;
            app.PIDs();
        end

        % Value changed function: xe
        function xeValueChanged(app, event)
            app.xe.Value = event.Value;
            app.PIDs();
        end

        % Value changed function: g
        function gValueChanged(app, event)
            app.g.Value = event.Value;
            app.PIDs();
        end

        % Value changed function: m
        function mValueChanged(app, event)
            app.m.Value = event.Value;
            app.PIDs();
        end

        % Value changed function: fz
        function fzValueChanged(app, event)
            app.fz.Value = event.Value;
            app.PIDs();
        end

        % Value changed function: F_max
        function F_maxValueChanged(app, event)
            app.F_max.Value = event.Value;
            app.PIDs();
        end

        % Value changing function: Kp_v
        function Kp_vValueChanging(app, event)
            app.Kp_v.Value = event.Value;
            app.PIDs();
        end

        % Value changing function: Ki_v
        function Ki_vValueChanging(app, event)
            app.Ki_v.Value = event.Value;
            app.PIDs();
        end

        % Value changed function: disturbance_1
        function disturbance_1ValueChanged(app, event)
            app.disturbance(1) = app.disturbance_1.Value;
            app.PIDs();
        end

        % Value changed function: disturbance_2
        function disturbance_2ValueChanged(app, event)
            app.disturbance(2) = app.disturbance_2.Value;
            app.PIDs();
        end

        % Value changed function: disturbance_3
        function disturbance_3ValueChanged(app, event)
            app.disturbance(3) = app.disturbance_3.Value;
            app.PIDs();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {476, 476};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {342, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = {'位置差值'; '比例系数'};
            app.Kp_x = uislider(app.LeftPanel);
            app.Kp_x.Limits = [0 10];
            app.Kp_x.ValueChangingFcn = createCallbackFcn(app, @Kp_xValueChanging, true);
            app.Kp_x.Value = 1;
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = {'位置差值'; '积分系数'};
            app.Ki_x = uislider(app.LeftPanel);
            app.Ki_x.Limits = [0 10];
            app.Ki_x.ValueChangingFcn = createCallbackFcn(app, @Ki_xValueChanging, true);
            app.Ki_x.Value = 1;
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = '重力加速度';
            app.g = uieditfield(app.LeftPanel, 'numeric');
            app.g.ValueChangedFcn = createCallbackFcn(app, @gValueChanged, true);
            app.g.Value = 9.81;
            app.Label_6 = uilabel(app.LeftPanel);
            app.Label_6.Text = '无人机质量';
            app.m = uieditfield(app.LeftPanel, 'numeric');
            app.m.ValueChangedFcn = createCallbackFcn(app, @mValueChanged, true);
            app.m.Value = 1;
            app.Label_5 = uilabel(app.LeftPanel);
            app.Label_5.Text = '期望位置';
            app.xe = uieditfield(app.LeftPanel, 'numeric');
            app.xe.ValueChangedFcn = createCallbackFcn(app, @xeValueChanged, true);
            app.xe.Value = 5;
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = '阻尼系数';
            app.fz = uieditfield(app.LeftPanel, 'numeric');
            app.fz.ValueChangedFcn = createCallbackFcn(app, @fzValueChanged, true);
            app.fz.Value = 0.1;
            app.EditField_5Label = uilabel(app.LeftPanel);
            app.EditField_5Label.Text = '最大出力';
            app.F_max = uieditfield(app.LeftPanel, 'numeric');
            app.F_max.ValueChangedFcn = createCallbackFcn(app, @F_maxValueChanged, true);
            app.F_max.Value = 20;
            app.Label_7 = uilabel(app.LeftPanel);
            app.Label_7.Text = '调节时间';
            app.TextArea = uitextarea(app.LeftPanel);
            app.Label_8 = uilabel(app.LeftPanel);
            app.Label_8.Text = {'速度差值'; '比例系数'};
            app.Kp_v = uislider(app.LeftPanel);
            app.Kp_v.Limits = [0 50];
            app.Kp_v.ValueChangingFcn = createCallbackFcn(app, @Kp_vValueChanging, true);
            app.Kp_v.Value = 10;
            app.Label_9 = uilabel(app.LeftPanel);
            app.Label_9.Text = {'速度差值'; '积分系数'};
            app.Ki_v = uislider(app.LeftPanel);
            app.Ki_v.Limits = [0 50];
            app.Ki_v.ValueChangingFcn = createCallbackFcn(app, @Ki_vValueChanging, true);
            app.Ki_v.Value = 10;
            app.Label_10 = uilabel(app.LeftPanel);
            app.Label_10.Text = '扰动幅度';
            app.disturbance_2 = uieditfield(app.LeftPanel, 'numeric');
            app.disturbance_2.ValueChangedFcn = createCallbackFcn(app, @disturbance_2ValueChanged, true);
            app.disturbance_2.Value = 1;
            app.Label_11 = uilabel(app.LeftPanel);
            app.Label_11.Text = '扰动频率';
            app.disturbance_3 = uieditfield(app.LeftPanel, 'numeric');
            app.disturbance_3.ValueChangedFcn = createCallbackFcn(app, @disturbance_3ValueChanged, true);
            app.disturbance_3.Value = 1;
            app.Label_12 = uilabel(app.LeftPanel);
            app.Label_12.Text = '扰动是否开启';
            app.disturbance_1 = uiswitch(app.LeftPanel, 'slider');
            app.disturbance_1.Items = {'关', '开'};
            app.disturbance_1.ValueChangedFcn = createCallbackFcn(app, @disturbance_1ValueChanged, true);
            app.disturbance_1.Value = '关';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '实时位置与速度')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'x')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = visible_pi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end